classdef Addamask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MasqueradeAppUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SelectImageSourceDropDown       matlab.ui.control.DropDown
        SelectImageSourceDropDownLabel  matlab.ui.control.Label
        AddaMaskDropDown                matlab.ui.control.DropDown
        verticaladjustmentslider        matlab.ui.control.Slider
        VerticalAdjustmentSliderLabel   matlab.ui.control.Label
        horizontaladjustmentslider      matlab.ui.control.Slider
        HorizontalAdjustmentSliderLabel  matlab.ui.control.Label
        AddaMaskLabel                   matlab.ui.control.Label
        SaveImageButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        TakeaPhotoButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        cam
        markedImg
        img
        bbox
        masks = load("masks.mat"); % File with all the masks
        image

        wlist
        inImg
        outImg
        photoTimer
        photoTaken = false % flag to indicate photo has not been taken
        isClosing=false % flag to indicate the app is closing

    end


    methods (Access = private)

        function bbox = findfaces(app)
            % Function to find faces
            faceDetector = vision.CascadeObjectDetector();
            x = faceDetector(app.img);
            bbox = x(:, [2 1 3 4]);
        end

        function outImg = markFaces(app)
            % Function to mark faces
            bboxList = app.bbox(:,[2 1 3 4]);
            outImg = insertObjectAnnotation(app.img, 'rectangle', bboxList, 1:size(bboxList, 1), 'FontSize', 18, 'LineWidth', 3);

        end


        function outImg = addmasks(app,markFacesFlag)
            % Function to add masks
            app.inImg = app.markedImg;

            if markFacesFlag
                app.markedImg = app.markFaces(); % Mark faces if required
            else
                app.markedImg = app.img; % Use the original image if not marking faces
            end
            maskImg = app.image;
            bboxList = app.bbox;

            % Get face locations, topleft x y of bounding box
            faceLoc = [bboxList(:,1) bboxList(:,2)];

            % Get face sizes, row and column size
            faceSize = [bboxList(:,4) bboxList(:,3)];

            % Bias the face locations if maskBias is provided
            maskBias = [app.verticaladjustmentslider.Value app.horizontaladjustmentslider.Value];
            faceLoc = faceLoc + floor(maskBias.*faceSize/100);

            for idx = 1:size(bboxList, 1)
                sz = max(size(maskImg));
                scale = bboxList(idx, 4) / sz;
                resizedMask = imresize(maskImg, scale);
                app.image = resizedMask;
                % facePosition = bboxList(idx, 1:2);
                app.inImg = app.superImpose(app.inImg, resizedMask, faceLoc(idx,:));
            end

            if isempty(bboxList)
                uialert(app.MasqueradeAppUIFigure, "Cannot detect faces. Please take another photo.", "Bad face detection", "icon", "warning")
                outImg = app.inImg;
                % Renable selectimagesource
                app.SelectImageSourceDropDown.Visible = "on";
                app.SelectImageSourceDropDown.Enable ="on";
                app.SaveImageButton.Visible = "off";
                app.SaveImageButton.Enable = "off";
            else
                outImg = app.inImg;
            end
        end


        function fused = superImpose(~,im,overlay,atPosition)
            % Superimpose function
            fused = im;
            for r = 1:size(overlay, 1)
                for c = 1:size(overlay, 2)
                    overlayRGBPixel = overlay(r, c, :);
                    if any(overlayRGBPixel)
                        imageR = atPosition(1) + r - 1;
                        imageC = atPosition(2) + c - 1;
                        if imageR >= 1 && imageR <= size(fused, 1) && imageC >= 1 && imageC <= size(fused, 2)
                            fused(imageR, imageC, :) = overlayRGBPixel;
                        end
                    end
                end
            end

        end


        % Scale image to fit axes function
        function scaleddisp(~,axesHandle,imag)

            % Check if the provided axes handle is valid
            if ishandle(axesHandle)
                % Display the image and get handle
                I = imshow(imag, 'Parent', axesHandle);

                % Get the size of the specified axes
                axesPosition = axesHandle.Position; % [left bottom width height]
                axesWidth = axesPosition(3);
                axesHeight = axesPosition(4);

                % Get the size of the image
                [imgHeight, imgWidth, ~] = size(imag);

                % Calculate the aspect ratios
                imageAspectRatio = imgWidth / imgHeight;
                axesAspectRatio = axesWidth / axesHeight;

                % Determine the scaling factors to maintain the aspect ratio
                if imageAspectRatio > axesAspectRatio
                    % Image is wider relative to its height than the axes
                    newWidth = axesWidth;
                    newHeight = axesWidth / imageAspectRatio;
                else
                    % Image is taller relative to its width than the axes
                    newHeight = axesHeight;
                    newWidth = axesHeight * imageAspectRatio;
                end

                % Calculate new XData and YData to fit the aspect ratio
                XData = [(axesWidth - newWidth) / 2, (axesWidth + newWidth) / 2];
                YData = [(axesHeight - newHeight) / 2, (axesHeight + newHeight) / 2];

                % Set the new XData and YData
                I.XData = XData;
                I.YData = YData;

                % Update the limits of the axes
                axesHandle.XLim = [XData(1) XData(2)];
                axesHandle.YLim = [YData(1) YData(2)];
            else
                error('Invalid axes handle provided.');
            end
        end


        function captureFrames(app)

            try
                if ~app.isClosing
                    if isvalid(app.cam) % Check if webcam is valid
                        frame = fliplr(snapshot(app.cam)); % Capture a frame
                        app.img = frame;

                        if ishandle(app.UIAxes)
                            scaleddisp(app, app.UIAxes, app.img); % Update UIAxes
                        end
                    end
                end
            catch ME
                % Display the error message for debugging (optional)
                disp(ME.message);

                % Stop the timer on error
                if isvalid(app.photoTimer)
                    stop(app.photoTimer);
                    % delete(app.photoTimer);
                end
                return; % Exit the function
            end

        end

        function stopCapture(app)

            % Ensure the timer is stopped and deleted if the button is disabled
            if strcmp(app.TakeaPhotoButton.Enable, "off")
                if isvalid(app.photoTimer)
                    stop(app.photoTimer);
                    % delete(app.photoTimer);
                    app.photoTimer = []; % Clear the reference for safety
                end
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            % Get the list of available webcams
            app.wlist = webcamlist;
            items = ["Choose"; string(app.wlist); "Select your own Image"];

            % Update the select image source dropdown menu
            set(app.SelectImageSourceDropDown, 'Items', items);

            % Populate mask dropdown menu
            app.AddaMaskDropDown.Items = ["Choose a mask"; string(fieldnames(app.masks))];


        end

        % Value changed function: SelectImageSourceDropDown
        function SelectImageSourceDropDownValueChanged(app, event)
            % Retrieve the selected value from dropdown
            selectedValue = app.SelectImageSourceDropDown.Value;


            switch selectedValue
                case "Select your own Image"

                    % Hide photo button

                    app.TakeaPhotoButton.Enable = "off";
                    app.TakeaPhotoButton.Visible = "off";
                    app.SaveImageButton.Visible = "on";
                    app.SaveImageButton.Enable = "off";

                    % Open file selection dialog
                    [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif)'}, 'Select an Image');

                    % Attempt to read the image
                    if isequal(file,0) || isequal(path,0)
                        return; % User cancelled file selection
                    end

                    try
                        fullImagePath = fullfile(path, file);
                        app.img = imread(fullImagePath);
                        scaleddisp(app, app.UIAxes, app.img);
                    catch
                        uialert(app.MasqueradeAppUIFigure, "Cannot read image. Please select another image.", "Warning");
                    end


                case {app.wlist{:}}
                    % Show and enable take a photo button
                    app.TakeaPhotoButton.Enable = "on";
                    app.TakeaPhotoButton.Visible = "on";


                    % Handle webcam selection
                    camIndex = strcmp(selectedValue, app.wlist);
                    if any(camIndex)
                        camIndex = find(camIndex);
                        app.cam = webcam(camIndex);

                        % Create and configure the timer object
                        app.photoTimer = timer;

                        % Set timer properties
                        app.photoTimer.Period = 0.1; % Set the interval for updates
                        app.photoTimer.ExecutionMode = 'fixedSpacing';
                        app.photoTimer.TimerFcn = @(~, ~) captureFrames(app);

                        % Start the timer when the photo button is enabled
                        if strcmp(app.TakeaPhotoButton.Enable, "on")
                            start(app.photoTimer);
                        end

                    end
            end

            % Reset dropdown value and disable it
            app.SelectImageSourceDropDown.Value = "Choose";
            app.SelectImageSourceDropDown.Enable = "off";
            figure(app.MasqueradeAppUIFigure);
            app.inImg = app.img;


            app.AddaMaskLabel.Visible = "on";
            app.AddaMaskLabel.Enable = "on";
            app.AddaMaskDropDown.Visible = "on";
            app.AddaMaskDropDown.Enable = "on";
            app.verticaladjustmentslider.Visible = "on";
            app.verticaladjustmentslider.Enable = "on";
            app.horizontaladjustmentslider.Visible = "on";
            app.horizontaladjustmentslider.Enable = "on";
            app.ResetButton.Visible= "on";
            app.ResetButton.Enable = "on";

        end

        % Button pushed function: TakeaPhotoButton
        function TakeaPhotoButtonPushed(app, event)
            app.photoTaken = true; % Mark that the photo has been taken

            %  Stop the timer
            if isvalid(app.photoTimer)
                stop(app.photoTimer);
            end

            % Acquire webcam image
            app.img = fliplr(snapshot(app.cam));

            % Show acquired image
            scaleddisp(app, app.UIAxes2, app.img);
            clear('app.cam');

            % Disable Take a Photo Button
            app.TakeaPhotoButton.Enable = "off";
            app.TakeaPhotoButton.Text="Photo Taken";
            app.SelectImageSourceDropDown.Enable = "off";

            app.TakeaPhotoButton.Visible = "on";
            app.TakeaPhotoButton.Enable = "off";
            app.SaveImageButton.Visible = "on";
            app.SaveImageButton.Enable = "on";

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Restore formatting to original formatting

            % settings = app.defaultSettings;
            app.SelectImageSourceDropDown.Visible ="on";
            app.SelectImageSourceDropDown.Enable ="on";
            % applysettings(app, settings);

            cla(app.UIAxes, "reset");
            app.UIAxes.BoxStyle = "full";
            app.UIAxes.Box = "on";
            app.UIAxes.Clipping = "on";
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = [];
            cla(app.UIAxes2, "reset");
            app.UIAxes2.BoxStyle = "full";
            app.UIAxes2.Box = "on";
            app.UIAxes2.Clipping = "on";
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = [];


            app.TakeaPhotoButton.Text = "Take a Photo";
            app.TakeaPhotoButton.Visible = "off";
            app.TakeaPhotoButton.Enable = "off";
            app.SaveImageButton.Visible ="off";
            app.SaveImageButton.Enable = "off";
            app.AddaMaskLabel.Enable ="off";
            app.AddaMaskDropDown.Enable ="off";
            app.verticaladjustmentslider.Enable="off";

            app.horizontaladjustmentslider.Enable ="off";


        end

        % Callback function: AddaMaskDropDown, UIAxes2
        function AddaMaskDropDownOpening(app, event)
            if ~startsWith(app.AddaMaskDropDown.Value, "Choose")
                % Load the selected mask image
                app.image = app.masks.(app.AddaMaskDropDown.Value);

                % Find bounding boxes around faces
                app.bbox = app.findfaces();

                % Mark faces on the image
                app.markedImg = app.markFaces();

                % Add masks to the marked image
                app.verticaladjustmentslider.Value  = 10;
                app.horizontaladjustmentslider.Value = 0;
                maskedImg = app.addmasks(true);

                % Display the masked image
                % imshow(app.img, 'Parent',app.UIAxes2);
                scaleddisp(app,app.UIAxes2,maskedImg);

            end

            app.AddaMaskDropDown.Value = "Choose a mask";
            app.SaveImageButton.Enable ="on";
            app.outImg = maskedImg;


        end

        % Value changed function: horizontaladjustmentslider, 
        % ...and 1 other component
        function verticaladjustmentsliderValueChanged(app, event)
            % Call addmasks without marking faces
            maskedImg = app.addmasks(false);

            % Display the masked image
            scaleddisp(app, app.UIAxes2, maskedImg);
            app.outImg = maskedImg;
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            % Prompt the user to select a file name and folder
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.jpeg'; '*.bmp'; '*.tiff'}, ...
                'Save Image As');

            % Check if the user canceled the dialog
            if isequal(file, 0) || isequal(path, 0)
                % User canceled the file dialog
                return;
            end

            % Construct the full file path
            fullPath = fullfile(path, file);

            % Ensure the image data is available
            if isprop(app,'outImg')
                % Save the image
                try
                    imwrite(app.outImg, fullPath);
                    % Notify the user
                    uialert(app.MasqueradeAppUIFigure, ['Image saved to ' fullPath], 'Save Successful');
                catch ME
                    % Handle any errors that occur during saving
                    uialert(app.FunhouseFiltersUIFigure, ['Failed to save image: ' ME.message], 'Save Error');
                end
            else
                % Notify the user if no image is available
                uialert(app.MasqueradeAppUIFigure, 'No image available to save.', 'Save Error');
            end
            figure(app.MasqueradeAppUIFigure)
        end

        % Close request function: MasqueradeAppUIFigure
        function MasqueradeAppUIFigureCloseRequest(app, event)
            app.isClosing = true; % Set the closing flag

            try
                % Stop the timer if it is running
                if ~isempty(app.photoTimer) && strcmp(app.photoTimer.Running, 'on')
                    stop(app.photoTimer);
                end

                % Close the UI figure if it is valid
                if isvalid(app.MasqueradeAppUIFigure)
                    delete(app.MasqueradeAppUIFigure); % Close the app UI figure
                end

            catch ME
                % Catch and display any errors during the shutdown process
                disp('Error occurred during shutdown:');
                disp(ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MasqueradeAppUIFigure = uifigure('Visible', 'off');
            app.MasqueradeAppUIFigure.Name = 'Masquerade App';
            app.MasqueradeAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @MasqueradeAppUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.MasqueradeAppUIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @AddaMaskDropDownOpening, true);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TakeaPhotoButton = uibutton(app.GridLayout, 'push');
            app.TakeaPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @TakeaPhotoButtonPushed, true);
            app.TakeaPhotoButton.Enable = 'off';
            app.TakeaPhotoButton.Visible = 'off';
            app.TakeaPhotoButton.Text = 'Take a Photo';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.SaveImageButton = uibutton(app.GridLayout, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Enable = 'off';
            app.SaveImageButton.Text = 'Save Image';
            app.AddaMaskLabel = uilabel(app.GridLayout);
            app.AddaMaskLabel.Enable = 'off';
            app.AddaMaskLabel.Text = 'Add a Mask';
            app.HorizontalAdjustmentSliderLabel = uilabel(app.GridLayout);
            app.HorizontalAdjustmentSliderLabel.Enable = 'off';
            app.HorizontalAdjustmentSliderLabel.Text = 'Horizontal Adjustment';
            app.horizontaladjustmentslider = uislider(app.GridLayout);
            app.horizontaladjustmentslider.Limits = [-100 100];
            app.horizontaladjustmentslider.ValueChangedFcn = createCallbackFcn(app, @verticaladjustmentsliderValueChanged, true);
            app.horizontaladjustmentslider.Enable = 'off';
            app.VerticalAdjustmentSliderLabel = uilabel(app.GridLayout);
            app.VerticalAdjustmentSliderLabel.Enable = 'off';
            app.VerticalAdjustmentSliderLabel.Text = 'Vertical Adjustment';
            app.verticaladjustmentslider = uislider(app.GridLayout);
            app.verticaladjustmentslider.Limits = [-100 100];
            app.verticaladjustmentslider.ValueChangedFcn = createCallbackFcn(app, @verticaladjustmentsliderValueChanged, true);
            app.verticaladjustmentslider.Enable = 'off';
            app.AddaMaskDropDown = uidropdown(app.GridLayout);
            app.AddaMaskDropDown.ValueChangedFcn = createCallbackFcn(app, @AddaMaskDropDownOpening, true);
            app.AddaMaskDropDown.Enable = 'off';
            app.SelectImageSourceDropDownLabel = uilabel(app.GridLayout);
            app.SelectImageSourceDropDownLabel.Text = 'Select Image Source';
            app.SelectImageSourceDropDown = uidropdown(app.GridLayout);
            app.SelectImageSourceDropDown.Items = {'1', '2'};
            app.SelectImageSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectImageSourceDropDownValueChanged, true);
            app.SelectImageSourceDropDown.Value = '1';
            % Show the figure after all components are created
            app.MasqueradeAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Addamask

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MasqueradeAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MasqueradeAppUIFigure)
        end
    end
end